classdef TTP_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ComponentManagerTab             matlab.ui.container.Tab
        dataType                        matlab.ui.container.TabGroup
        RequiredTab                     matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        ToDataScreeningButton           matlab.ui.control.Button
        CASisusedAsauniqueidentifiertolinkdatafromLabel_2  matlab.ui.control.Label
        ImportDataButton                matlab.ui.control.Button
        ClearTableButton                matlab.ui.control.Button
        SaveTableButton                 matlab.ui.control.Button
        AddDataButton                   matlab.ui.control.Button
        ManualDataEntryWindowTextArea   matlab.ui.control.TextArea
        ManualDataEntryWindowTextAreaLabel  matlab.ui.control.Label
        VariableSelectorButtonGroup     matlab.ui.container.ButtonGroup
        CASButton                       matlab.ui.control.ToggleButton
        ComponentButton                 matlab.ui.control.ToggleButton
        cp_J_per_K_molButton            matlab.ui.control.ToggleButton
        cp_temperature_KButton          matlab.ui.control.ToggleButton
        enthalpy_vaporization_kJ_per_molButton  matlab.ui.control.ToggleButton
        enthalpy_vaporization_temperature_KButton  matlab.ui.control.ToggleButton
        saturation_pressure_kPaButton   matlab.ui.control.ToggleButton
        saturation_temperature_KButton  matlab.ui.control.ToggleButton
        criticalPressure_kPaButton      matlab.ui.control.ToggleButton
        criticalTemperature_KButton     matlab.ui.control.ToggleButton
        AllinthissectionisusedforLabel  matlab.ui.control.Label
        CASisusedAsauniqueidentifiertolinkdatafromLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        DataScreeningTab                matlab.ui.container.Tab
        ScreeningProcessTab             matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        StartScreeningButton            matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        ScreeningTypeTab                matlab.ui.container.Tab
        AutoViaRegressionTreesButton    matlab.ui.control.Button
        ManualButton_2                  matlab.ui.control.Button
        ManualTab_2                     matlab.ui.container.Tab
        UsetheplotbrushtooltomarkupdataLabel  matlab.ui.control.Label
        CompleteManualScreeningButton   matlab.ui.control.Button
        RemoveMarkedOutliersButton      matlab.ui.control.Button
        AutoTab                         matlab.ui.container.Tab
        ToComponentManagerButton        matlab.ui.control.Button
        PlotAxisPanel                   matlab.ui.container.Panel
        ComponentDropDown               matlab.ui.control.DropDown
        ComponentLabel                  matlab.ui.control.Label
        PlotDataButton                  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        YAxisDropDown                   matlab.ui.control.DropDown
        YAxisLabel                      matlab.ui.control.Label
        XAxisDropDown                   matlab.ui.control.DropDown
        XAxisLabel                      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)

            % Get the column headers from UITable
            columnHeaders = app.UITable.ColumnName;
        
            % Get the selected X and Y axis columns from the dropdowns
            selectedXColumn = app.XAxisDropDown.Value;
            selectedYColumn = app.YAxisDropDown.Value;
        
            % Find the index of the selected columns in the column headers
            xColumnIdx = find(strcmp(columnHeaders, selectedXColumn));
            yColumnIdx = find(strcmp(columnHeaders, selectedYColumn));
        
            % Extract the table data
                    % Get the value selected in the ComponentDropDown (file name)
                    selectedFile = app.ComponentDropDown.Value;
                
                    % Define the folder path where the files are stored
                    if ispc
                        userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
                    else
                        userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
                    end
                    dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', 'Experimental_Data');
                
                    % Create the full path to the selected file
                    fullFilePath = fullfile(dataFolder, selectedFile);
                
                    % Check if the file exists
                    if ~exist(fullFilePath, 'file')
                        % Display warning if the file does not exist
                        uialert(app.UIFigure, 'The selected file could not be found.', 'File Error');
                        return;
                    end
                
                    % Read the data from the file (assuming a CSV format)
                    opts = detectImportOptions(fullFilePath);
                    tableData = readtable(fullFilePath, opts);
        
            % Get the data from the selected columns for X and Y
            xData = [tableData{:, xColumnIdx}]; % Convert cell array to numerical array
            yData = [tableData{:, yColumnIdx}]; % Convert cell array to numerical array
        
            % Check if the data extracted is numeric
            if ~isnumeric(xData) || ~isnumeric(yData)
                uialert(app.UIFigure, 'The selected columns must contain numeric data for plotting.', 'Plot Error');
                return;
            end
        
            % Modify the column names for the labels by replacing "_" with a space
            modifiedXLabel = strrep(selectedXColumn, '_', ' ');
            modifiedYLabel = strrep(selectedYColumn, '_', ' ');
        
            % Plot the data on the UIAxes
            scatter(app.UIAxes, xData, yData);
        
            % Label the axes
            xlabel(app.UIAxes, modifiedXLabel);
            ylabel(app.UIAxes, modifiedYLabel);
            title(app.UIAxes, sprintf('Plot of %s vs %s', modifiedYLabel, modifiedXLabel));


        end

        % Button pushed function: StartScreeningButton
        function StartScreeningButtonPushed(app, event)
            
            % Set the selected tab in the TabGroup to ScreeningTab
            app.ScreeningProcessTab.SelectedTab = app.ScreeningTypeTab;

        end

        % Button pushed function: ToDataScreeningButton
        function ToDataScreeningButtonPushed(app, event)
           % Set the selected tab in the TabGroup to ScreeningTab
            app.TabGroup.SelectedTab = app.DataScreeningTab;
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            % Open file selection dialog for CSV files
            [fileName, filePath] = uigetfile('*.csv', 'Select a CSV File to Import');
            
            % Check if the user did not cancel the file selection
            if isequal(fileName, 0)
                % User canceled the file selection
                return;
            end
        
            % Construct the full file path
            fullFilePath = fullfile(filePath, fileName);
        
            % Read the CSV file into a table
            try
                importedTable = readtable(fullFilePath);
            catch
                % Alert the user if there was an error reading the CSV
                uialert(app.UIFigure, 'Failed to read the CSV file. Please make sure it is formatted correctly.', 'Read Error');
                return;
            end
        
            % Convert the imported table to a cell array
            importedData = table2cell(importedTable);
        
            % Update the UITable's data
            app.UITable.Data = importedData;
        
            % Inform the user that the CSV file has been imported successfully
            uialert(app.UIFigure, 'Data has been successfully imported from the CSV file.', 'Import Successful');
        end

        % Button pushed function: ClearTableButton
        function ClearTableButtonPushed(app, event)
            % Set the UITable's Data property to an empty cell array to clear the table
            app.UITable.Data = {};
        end

        % Button pushed function: AddDataButton
        function AddDataButtonPushed(app, event)
                         % Get the data entered by the user
            userInput = app.ManualDataEntryWindowTextArea.Value; % Get input as a string
        
            % Split the user input based on delimiters (commas, new lines, spaces, tabs)
            rows = split(userInput, '\n'); % Split input into rows based on new lines
        
            % Determine if the input has multiple rows and columns
            numRows = length(rows);
            numCols = cellfun(@(x) length(split(x, {',', '\t', ' '})), rows);
        
            % If the number of columns is inconsistent or more than one column and one row, show an error
            if numRows > 1 && any(numCols > 1)
                uialert(app.UIFigure, 'Invalid input format. Please enter either a row or a column vector. Matrix input is not accepted.', 'Input Error');
                return;
            end
        
            % Flatten the input into a single array of entries
            dataEntries = split(userInput, {',', '\n', '\t', ' '});
            dataEntries = strtrim(dataEntries); % Trim any extra whitespace from each entry
            dataEntries(cellfun('isempty', dataEntries)) = []; % Remove empty cells
        
            % Determine if the data is intended to be a number or a string
            selectedButton = app.VariableSelectorButtonGroup.SelectedObject;
        
            % Determine which column to add the data to based on the selected button
            if selectedButton == app.ComponentButton
                colIdx = 1;
                isNumericData = false; % Component name is expected as a string
            elseif selectedButton == app.CASButton
                colIdx = 2;
                isNumericData = false; % CAS is expected as a string (it can be formatted like "50-00-0")
            elseif selectedButton == app.criticalTemperature_KButton
                colIdx = 3;
                isNumericData = true;
            elseif selectedButton == app.criticalPressure_kPaButton
                colIdx = 4;
                isNumericData = true;
            elseif selectedButton == app.saturation_temperature_KButton
                colIdx = 5;
                isNumericData = true;
            elseif selectedButton == app.saturation_pressure_kPaButton
                colIdx = 6;
                isNumericData = true;
            elseif selectedButton == app.enthalpy_vaporization_temperature_KButton
                colIdx = 7;
                isNumericData = true;
            elseif selectedButton == app.enthalpy_vaporization_kJ_per_molButton
                colIdx = 8;
                isNumericData = true;
            elseif selectedButton == app.cp_temperature_KButton
                colIdx = 9;
                isNumericData = true;
            elseif selectedButton == app.cp_J_per_K_molButton
                colIdx = 10;
                isNumericData = true;
            else
                uialert(app.UIFigure, 'Please select a variable to enter data.', 'Selection Error');
                return;
            end
        
            % Validate and convert data entries
            if isNumericData
                % Convert all entries to numeric values
                numericValues = str2double(dataEntries);
                if any(isnan(numericValues))
                    uialert(app.UIFigure, 'Please enter valid numeric values.', 'Input Error');
                    return;
                end
                dataToAdd = num2cell(numericValues); % Convert to cell array for table
            else
                % If the data should be strings, make sure all entries are valid strings
                dataToAdd = cellfun(@char, dataEntries, 'UniformOutput', false); % Convert to cell array of strings
            end
        
            % Prepare new table data, replacing the entire selected column with new data
            currentData = app.UITable.Data;
        
            % Ensure that currentData has the correct number of rows
            maxRows = max(length(dataToAdd), size(currentData, 1));
            if isempty(currentData)
                currentData = cell(maxRows, 10); % Initialize with empty cells to fit all columns
            elseif size(currentData, 1) < maxRows
                currentData{maxRows, 10} = []; % Expand to have enough rows
            end
        
            % Replace the selected column with new data
            for i = 1:maxRows
                if i <= length(dataToAdd)
                    currentData{i, colIdx} = dataToAdd{i};
                else
                    currentData{i, colIdx} = []; % Clear old data if the new input is shorter
                end
            end
        
            % Update the table with the new data
            app.UITable.Data = currentData;
        
            % Clear the DataEntryTextArea after entering the data
            app.ManualDataEntryWindowTextArea.Value = '';
        end

        % Button pushed function: SaveTableButton
        function SaveTableButtonPushed(app, event)
            % Get the user's "Documents" folder path
            userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            if ~ispc
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            
            % Define the main data folder and the experimental subfolder
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data');
            experimentalFolder = fullfile(dataFolder, 'Experimental_Data');
            
            % Create folders if they don't exist
            if ~exist(dataFolder, 'dir')
                mkdir(dataFolder);
            end
            if ~exist(experimentalFolder, 'dir')
                mkdir(experimentalFolder);
            end
            
            % Extract the Component and CAS Number from the first row of the table
            tableData = app.UITable.Data; % Extract the data from the table
            if isempty(tableData) || size(tableData, 1) < 1 || size(tableData, 2) < 2
                uialert(app.UIFigure, 'Table must have at least one row with Component and CAS data.', 'Save Error');
                return;
            end
            
            componentName = tableData{1, 1}; % First row, first column
            casNumber = tableData{1, 2}; % First row, second column
            
            % Validate that both Component and CAS are non-empty strings
            if isempty(componentName) || isempty(casNumber) || ~ischar(componentName) || ~ischar(casNumber)
                uialert(app.UIFigure, 'Component name and CAS number must be non-empty strings in the first row.', 'Save Error');
                return;
            end
            
            % Remove special characters from the Component Name
            modifiedComponentName = regexprep(componentName, '[^a-zA-Z0-9]', '');
            
            % Construct the filename using the modified component name and CAS number
            dataFileName = sprintf('%s_%s.csv', modifiedComponentName, casNumber);
            
            % Define the full path for the .csv file
            dataFilePath = fullfile(experimentalFolder, dataFileName);
            
            % Convert cell array data to a table for easy writing to CSV
            headers = app.UITable.ColumnName; % Get the column headers
            tableDataCell = app.UITable.Data; % Get the cell array data
            dataTable = cell2table(tableDataCell, 'VariableNames', headers);
            
            % Save the table data to a CSV file
            try
                writetable(dataTable, dataFilePath);
                % Inform the user that data has been saved successfully
                uialert(app.UIFigure, sprintf('Data has been saved successfully as "%s".', dataFileName), 'Save Successful');
            catch ME
                % Inform the user if there was an error saving the data
                uialert(app.UIFigure, sprintf('Failed to save data: %s', ME.message), 'Save Error');
            end

        end

        % Drop down opening function: ComponentDropDown
        function ComponentDropDownOpening(app, event)
            % Get the user's "Documents" folder path
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
        
            % Define the main data folder and the experimental subfolder
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data');
            experimentalFolder = fullfile(dataFolder, 'Experimental_Data');
        
            % Check if the experimental folder exists
            if ~exist(experimentalFolder, 'dir')
                % Display warning if the folder does not exist
                uialert(app.UIFigure, 'No data files have been found. Experimental Data folder is missing.', 'Warning');
                return;
            end
        
            % Get a list of all files in the experimental folder
            dataFiles = dir(fullfile(experimentalFolder, '*.csv')); % You can change the extension if needed
        
            % Check if there are any files in the folder
            if isempty(dataFiles)
                % Display warning if no files are found
                uialert(app.UIFigure, 'No data files have been found in the Experimental Data folder.', 'Warning');
                return;
            end
        
            % Extract file names from the structure array
            fileNames = {dataFiles.name};
        
            % Update the dropdown menu items
            app.ComponentDropDown.Items = fileNames;
        end

        % Drop down opening function: XAxisDropDown
        function XAxisDropDownOpening(app, event)
            % Get the value selected in the ComponentDropDown (file name)
            selectedFile = app.ComponentDropDown.Value;

            % Define the folder path where the files are stored
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', 'Experimental_Data');
        
            % Create the full path to the selected file
            fullFilePath = fullfile(dataFolder, selectedFile);
        
            % Check if the file exists
            if ~exist(fullFilePath, 'file')
                % Display warning if the file does not exist
                uialert(app.UIFigure, 'The selected file could not be found.', 'File Error');
                return;
            end
        
            % Read the data from the file (assuming a CSV format)
            opts = detectImportOptions(fullFilePath);
            tableData = readtable(fullFilePath, opts);
        
            % Extract column names from the loaded table
            columnHeaders = tableData.Properties.VariableNames;
        
            % Update the SelectionDropDown items with the column headers
            app.XAxisDropDown.Items = columnHeaders;
        end

        % Drop down opening function: YAxisDropDown
        function YAxisDropDownOpening(app, event)
            % Get the value selected in the ComponentDropDown (file name)
            selectedFile = app.ComponentDropDown.Value;
            
            % Define the folder path where the files are stored
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', 'Experimental_Data');
        
            % Create the full path to the selected file
            fullFilePath = fullfile(dataFolder, selectedFile);
        
            % Check if the file exists
            if ~exist(fullFilePath, 'file')
                % Display warning if the file does not exist
                uialert(app.UIFigure, 'The selected file could not be found.', 'File Error');
                return;
            end
        
            % Read the data from the file (assuming a CSV format)
            opts = detectImportOptions(fullFilePath);
            tableData = readtable(fullFilePath, opts);
        
            % Extract column names from the loaded table
            columnHeaders = tableData.Properties.VariableNames;
        
            % Update the SelectionDropDown items with the column headers
            app.YAxisDropDown.Items = columnHeaders;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ComponentManagerTab = uitab(app.TabGroup);
            app.ComponentManagerTab.Title = 'Component Manager';
            app.dataType = uitabgroup(app.ComponentManagerTab);
            app.RequiredTab = uitab(app.dataType);
            app.RequiredTab.Title = 'Required';
            app.UITable = uitable(app.RequiredTab);
            app.UITable.ColumnName = {'Component'; 'CAS'; 'criticalTemperature_K'; 'criticalPressure_kPa'; 'saturation_temperature_K'; 'saturation_pressure_kPa'; 'enthalpy_vaporization_temperature_K'; 'enthalpy_vaporization_kJ_per_mol'; 'cp_temperature_K'; 'cp_J_per_K_mol'};
            app.UITable.ColumnRearrangeable = 'on';
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.CASisusedAsauniqueidentifiertolinkdatafromLabel = uilabel(app.RequiredTab);
            app.CASisusedAsauniqueidentifiertolinkdatafromLabel.Text = 'CAS number is essential for linking and cross-referencing data between different tables.';
            app.AllinthissectionisusedforLabel = uilabel(app.RequiredTab);
            app.AllinthissectionisusedforLabel.Text = 'All data in this section is used for the optimization of alpha parameters, excluding data will require modification of optimization process. ';
            app.VariableSelectorButtonGroup = uibuttongroup(app.RequiredTab);
            app.VariableSelectorButtonGroup.Title = 'Variable Selector';
            app.criticalTemperature_KButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.criticalTemperature_KButton.Text = 'criticalTemperature_K';
            app.criticalTemperature_KButton.Value = true;
            app.criticalPressure_kPaButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.criticalPressure_kPaButton.Text = 'criticalPressure_kPa';
            app.saturation_temperature_KButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.saturation_temperature_KButton.Text = 'saturation_temperature_K';
            app.saturation_pressure_kPaButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.saturation_pressure_kPaButton.Text = 'saturation_pressure_kPa';
            app.enthalpy_vaporization_temperature_KButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.enthalpy_vaporization_temperature_KButton.Text = 'enthalpy_vaporization_temperature_K';
            app.enthalpy_vaporization_kJ_per_molButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.enthalpy_vaporization_kJ_per_molButton.Text = 'enthalpy_vaporization_kJ_per_mol';
            app.cp_temperature_KButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.cp_temperature_KButton.Text = 'cp_temperature_K';
            app.cp_J_per_K_molButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.cp_J_per_K_molButton.Text = 'cp_J_per_K_mol';
            app.ComponentButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.ComponentButton.Text = 'Component';
            app.CASButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.CASButton.Text = 'CAS';
            app.ManualDataEntryWindowTextAreaLabel = uilabel(app.RequiredTab);
            app.ManualDataEntryWindowTextAreaLabel.Text = 'Manual Data Entry Window';
            app.ManualDataEntryWindowTextArea = uitextarea(app.RequiredTab);
            app.AddDataButton = uibutton(app.RequiredTab, 'push');
            app.AddDataButton.ButtonPushedFcn = createCallbackFcn(app, @AddDataButtonPushed, true);
            app.AddDataButton.Text = 'Add Data';
            app.SaveTableButton = uibutton(app.RequiredTab, 'push');
            app.SaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableButtonPushed, true);
            app.SaveTableButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'save2_img.png');
            app.SaveTableButton.Text = 'Save Table';
            app.ClearTableButton = uibutton(app.RequiredTab, 'push');
            app.ClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTableButtonPushed, true);
            app.ClearTableButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'clear_img.png');
            app.ClearTableButton.Text = 'Clear Table';
            app.ImportDataButton = uibutton(app.RequiredTab, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'import_image.png');
            app.ImportDataButton.Text = 'Import Data     ';
            app.CASisusedAsauniqueidentifiertolinkdatafromLabel_2 = uilabel(app.RequiredTab);
            app.CASisusedAsauniqueidentifiertolinkdatafromLabel_2.Text = 'unsaved files will be lost!';
            app.ToDataScreeningButton = uibutton(app.RequiredTab, 'push');
            app.ToDataScreeningButton.ButtonPushedFcn = createCallbackFcn(app, @ToDataScreeningButtonPushed, true);
            app.ToDataScreeningButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'screening_image.png');
            app.ToDataScreeningButton.Text = 'To Data Screening';
            app.Image = uiimage(app.RequiredTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'GUI_files', 'image', 'arrowR_image.png');
            app.DataScreeningTab = uitab(app.TabGroup);
            app.DataScreeningTab.Title = 'Data Screening';
            app.UIAxes = uiaxes(app.DataScreeningTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotAxisPanel = uipanel(app.DataScreeningTab);
            app.PlotAxisPanel.Title = 'Plot Axis';
            app.XAxisLabel = uilabel(app.PlotAxisPanel);
            app.XAxisLabel.Text = 'X-Axis :';
            app.XAxisDropDown = uidropdown(app.PlotAxisPanel);
            app.XAxisDropDown.Items = {'Select X-Axis Variable'};
            app.XAxisDropDown.DropDownOpeningFcn = createCallbackFcn(app, @XAxisDropDownOpening, true);
            app.XAxisDropDown.Value = 'Select X-Axis Variable';
            app.YAxisLabel = uilabel(app.PlotAxisPanel);
            app.YAxisLabel.Text = 'Y-Axis :';
            app.YAxisDropDown = uidropdown(app.PlotAxisPanel);
            app.YAxisDropDown.Items = {'Select Y-Axis Variable'};
            app.YAxisDropDown.DropDownOpeningFcn = createCallbackFcn(app, @YAxisDropDownOpening, true);
            app.YAxisDropDown.Value = 'Select Y-Axis Variable';
            app.Label = uilabel(app.PlotAxisPanel);
            app.Label.Text = '';
            app.PlotDataButton = uibutton(app.PlotAxisPanel, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.ComponentLabel = uilabel(app.PlotAxisPanel);
            app.ComponentLabel.Text = 'Component :';
            app.ComponentDropDown = uidropdown(app.PlotAxisPanel);
            app.ComponentDropDown.Items = {'Select File'};
            app.ComponentDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ComponentDropDownOpening, true);
            app.ComponentDropDown.Value = 'Select File';
            app.ToComponentManagerButton = uibutton(app.DataScreeningTab, 'push');
            app.ToComponentManagerButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'tableBig_img.png');
            app.ToComponentManagerButton.Text = {'To Component '; 'Manager'};
            app.ScreeningProcessTab = uitabgroup(app.DataScreeningTab);
            app.HomeTab = uitab(app.ScreeningProcessTab);
            app.HomeTab.Title = 'Home';
            app.SaveDataButton = uibutton(app.HomeTab, 'push');
            app.SaveDataButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'save2_img.png');
            app.SaveDataButton.Text = 'Save Data';
            app.StartScreeningButton = uibutton(app.HomeTab, 'push');
            app.StartScreeningButton.ButtonPushedFcn = createCallbackFcn(app, @StartScreeningButtonPushed, true);
            app.StartScreeningButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'screening_image.png');
            app.StartScreeningButton.Text = 'Start Screening  ';
            app.ScreeningTypeTab = uitab(app.ScreeningProcessTab);
            app.ScreeningTypeTab.Title = 'Screening  Type';
            app.ManualButton_2 = uibutton(app.ScreeningTypeTab, 'push');
            app.ManualButton_2.Text = 'Manual';
            app.AutoViaRegressionTreesButton = uibutton(app.ScreeningTypeTab, 'push');
            app.AutoViaRegressionTreesButton.Text = {'Auto  '; '( Via Regression Trees )'};
            app.ManualTab_2 = uitab(app.ScreeningProcessTab);
            app.ManualTab_2.Title = 'Manual';
            app.RemoveMarkedOutliersButton = uibutton(app.ManualTab_2, 'push');
            app.RemoveMarkedOutliersButton.Text = {'Remove '; 'Marked Outliers'};
            app.CompleteManualScreeningButton = uibutton(app.ManualTab_2, 'push');
            app.CompleteManualScreeningButton.Text = {'Complete Manual'; 'Screening'};
            app.UsetheplotbrushtooltomarkupdataLabel = uilabel(app.ManualTab_2);
            app.UsetheplotbrushtooltomarkupdataLabel.Text = 'Use the plot brush tool to mark up data.';
            app.AutoTab = uitab(app.ScreeningProcessTab);
            app.AutoTab.Title = 'Auto  ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TTP_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end